classdef AppGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LowPassFilterTab              matlab.ui.container.Tab
        LowPassFilterDropDown         matlab.ui.control.DropDown
        LowPassFilterDropDownLabel    matlab.ui.control.Label
        ImageResultLabel              matlab.ui.control.Label
        FourierFilterLabel            matlab.ui.control.Label
        FourierSpectrumLabel          matlab.ui.control.Label
        ImageInputLabel               matlab.ui.control.Label
        LoadimageButtonLPF            matlab.ui.control.Button
        ExampleimageLPF               matlab.ui.control.DropDown
        ExampleimageDropDownLabel     matlab.ui.control.Label
        resLPF                        matlab.ui.control.UIAxes
        hLPF                          matlab.ui.control.UIAxes
        fsLPF                         matlab.ui.control.UIAxes
        InputLPF                      matlab.ui.control.UIAxes
        HighPassFilterTab             matlab.ui.container.Tab
        HighPassFilterDropDown        matlab.ui.control.DropDown
        LowPassFilterDropDown_2Label  matlab.ui.control.Label
        ExampleimageHPF               matlab.ui.control.DropDown
        ExampleimageDropDownLabel_2   matlab.ui.control.Label
        ImageResultLabel_2            matlab.ui.control.Label
        FourierFilterLabel_2          matlab.ui.control.Label
        FourierSpectrumLabel_2        matlab.ui.control.Label
        ImageInputLabel_2             matlab.ui.control.Label
        LoadimageButtonHPF            matlab.ui.control.Button
        resHPF                        matlab.ui.control.UIAxes
        hHPF                          matlab.ui.control.UIAxes
        fsHPF                         matlab.ui.control.UIAxes
        InputHPF                      matlab.ui.control.UIAxes
        HistogramEqualizationTab      matlab.ui.container.Tab
        LoadImageButton               matlab.ui.control.Button
        ExampleImageHisteq            matlab.ui.control.DropDown
        ExampleImageDropDownLabel     matlab.ui.control.Label
        InputLabel                    matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        BlueAxesInput_2               matlab.ui.control.UIAxes
        RedAxesInput_2                matlab.ui.control.UIAxes
        GreenAxesInput_2              matlab.ui.control.UIAxes
        ImageInput_2                  matlab.ui.control.UIAxes
        BlueAxesInput                 matlab.ui.control.UIAxes
        RedAxesInput                  matlab.ui.control.UIAxes
        GreenAxesInput                matlab.ui.control.UIAxes
        ImageInput                    matlab.ui.control.UIAxes
        HistogramSpecificationTab     matlab.ui.container.Tab
        ReferenceLabel                matlab.ui.control.Label
        ExampleImageHisspec           matlab.ui.control.DropDown
        ExampleImageDropDownLabel_3   matlab.ui.control.Label
        LoadImageButtonHisspec        matlab.ui.control.Button
        InputLabel_3                  matlab.ui.control.Label
        ResultLabel_3                 matlab.ui.control.Label
        BlueAxesRef                   matlab.ui.control.UIAxes
        RedAxesRef                    matlab.ui.control.UIAxes
        GreenAxesRef                  matlab.ui.control.UIAxes
        ImageRef                      matlab.ui.control.UIAxes
        BlueAxesResultHisspec         matlab.ui.control.UIAxes
        RedAxesResultHisspec          matlab.ui.control.UIAxes
        GreenAxesResultHisspec        matlab.ui.control.UIAxes
        ImageResultHisspec            matlab.ui.control.UIAxes
        BlueAxesInputHisspec          matlab.ui.control.UIAxes
        RedAxesInputHisspec           matlab.ui.control.UIAxes
        GreenAxesInputHisspec         matlab.ui.control.UIAxes
        ImageInputHisspec             matlab.ui.control.UIAxes
    end

    % Method Program
    methods (Access = private)
        function lowPassFilter(app, imagePath)
            f = imread(imagePath);
            imagesc(app.InputLPF, f);

            f =  im2double(f);
            [M,N,O] = size(f);

            P = 2*M;
            Q = 2*N;
            fp = zeros(P,Q,O);

            for i = 1:P
                for j = 1:Q
                    if i <= M && j<= N
                        fp(i,j,:) = f(i,j,:);
                    else
                        fp(i,j,:) = 0;
                    end
                end
            end

            Fc = fftshift(fft2(im2gray(fp)));
            S2 = log(1+abs(Fc));
            imagesc(app.fsLPF, S2);

            F = zeros(P,Q,O);
            for i = 1:O
                F(:,:,i) = fft2(double(fp(:,:,i)));
            end
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);
            
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);

            switch app.LowPassFilterDropDown.Value
                case 'Ideal'
                    H = double(D <= D0);
                case 'Gaussian'
                    H = exp(-(D.^2)./(2*(D0^2))); 
                case 'Butterworth'
                    H = 1./(1 + (D./D0).^2);
            end

            H = fftshift(H);
            imagesc(app.hLPF, H);

            H = ifftshift(H);
            LPF_f2 = zeros(P,Q,O);
            for i = 1:O
                LPF_f = H.*F(:,:,i);
                LPF_f2(:,:,i) = real(ifft2(LPF_f));
            end
            imagesc(app.resLPF, LPF_f2(1:M,1:N,:));
        end

        function highPassFilter(app, imagePath)
            f = imread(imagePath);
            imagesc(app.InputHPF, f);

            f =  im2double(f);
            [M,N,O] = size(f);

            P = 2*M;
            Q = 2*N;
            fp = zeros(P,Q,O);

            for i = 1:P
                for j = 1:Q
                    if i <= M && j<= N
                        fp(i,j,:) = f(i,j,:);
                    else
                        fp(i,j,:) = 0;
                    end
                end
            end

            Fc = fftshift(fft2(im2gray(fp)));
            S2 = log(1+abs(Fc));
            imagesc(app.fsHPF, S2);

            F = zeros(P,Q,O);
            for i = 1:O
                F(:,:,i) = fft2(double(fp(:,:,i)));
            end
            D0 = 0.05 * P;
            u = 0:(P-1);
            v = 0:(Q-1);
            
            idx = find(u > P/2);
            u(idx) = u(idx) - P;
            idy = find(v > Q/2);
            v(idy) = v(idy) - Q;
            [V, U] = meshgrid(v, u);
            D = sqrt(U.^2+V.^2);

            switch app.HighPassFilterDropDown.Value
                case 'Ideal'
                    H = double(D <= D0);
                case 'Gaussian'
                    H = exp(-(D.^2)./(2*(D0^2))); 
                case 'Butterworth'
                    H = 1./(1 + (D./D0).^2);
            end
        
            H = 1 - H;
            H = fftshift(H);
            imagesc(app.hHPF, H);

            H = ifftshift(H);
            HPF_f2 = zeros(P,Q,O);
            for i = 1:O
                HPF_f = H.*F(:,:,i);
                HPF_f2(:,:,i) = real(ifft2(HPF_f));
            end
            imagesc(app.resHPF, HPF_f2(1:M,1:N,:));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InputLPF.Visible = 'off';
            app.InputLPF.Colormap = gray(256);
            axis(app.InputLPF, 'image');

            app.fsLPF.Visible = 'off';
            app.fsLPF.Colormap = gray(8);
            axis(app.fsLPF, 'image');

            app.hLPF.Visible = 'off';
            app.hLPF.Colormap = gray(256);
            axis(app.hLPF, 'image');

            app.resLPF.Visible = 'off';
            app.resLPF.Colormap = gray(256);
            axis(app.resLPF, 'image');

            app.InputHPF.Visible = 'off';
            app.InputHPF.Colormap = gray(256);
            axis(app.InputHPF, 'image');

            app.fsHPF.Visible = 'off';
            app.fsHPF.Colormap = gray(8);
            axis(app.fsHPF, 'image');

            app.hHPF.Visible = 'off';
            app.hHPF.Colormap = gray(256);
            axis(app.hHPF, 'image');

            app.resHPF.Visible = 'off';
            app.resHPF.Colormap = gray(256);
            axis(app.resHPF, 'image');

            app.ExampleimageLPFValueChanged()
            app.ExampleimageHPFValueChanged()
        end

        % Value changed function: ExampleimageLPF
        function ExampleimageLPFValueChanged(app, event)
            value = app.ExampleimageLPF.Value;
            switch value
                case 'Gedung Sate'
                    lowPassFilter(app, 'images/task1 (1).jpg')
                case 'Kupu-Kupu'
                    lowPassFilter(app, 'images/task1 (2).jpg')
                case 'Kapal'
                    lowPassFilter(app, 'images/task1 (3).jpg')
                case 'HerryPoter'
                    lowPassFilter(app, 'images/task1 (4).jpg')
                case 'Saturnus'
                    lowPassFilter(app, 'images/task1 (5).jpg')
                case 'Wajah'
                    lowPassFilter(app, 'images/task1 (6).jpg')
                case 'Polisi'
                    lowPassFilter(app, 'images/task1 (7).jpg')
            end
        end

        % Button pushed function: LoadimageButtonLPF
        function LoadimageButtonLPFPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               lowPassFilter(app, fname);
            end
        end

        % Value changed function: LowPassFilterDropDown
        function LowPassFilterDropDownValueChanged(app, event)
            app.ExampleimageLPFValueChanged(event);
        end

        % Value changed function: ExampleimageHPF
        function ExampleimageHPFValueChanged(app, event)
            value = app.ExampleimageHPF.Value;
            switch value
                case 'Rumah'
                    highPassFilter(app, 'images/task2 (1).jpg')
                case 'Kucing'
                    highPassFilter(app, 'images/task2 (2).jpg')
                case 'Bakar'
                    highPassFilter(app, 'images/task2 (3).jpg')
                case 'Gajah Duduk'
                    highPassFilter(app, 'images/task2 (4).png')
            end
        end

        % Value changed function: HighPassFilterDropDown
        function HighPassFilterDropDownValueChanged(app, event)
            app.ExampleimageHPFValueChanged(event);
        end

        % Button pushed function: LoadimageButtonHPF
        function LoadimageButtonHPFPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               highPassFilter(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LowPassFilterTab = uitab(app.TabGroup);
            app.LowPassFilterTab.Title = 'Low Pass Filter';
            app.InputLPF = uiaxes(app.LowPassFilterTab);
            app.InputLPF.XTick = [];
            app.InputLPF.YTick = [];
            app.fsLPF = uiaxes(app.LowPassFilterTab);
            app.fsLPF.XTick = [];
            app.fsLPF.YTick = [];
            app.hLPF = uiaxes(app.LowPassFilterTab);
            app.hLPF.XTick = [];
            app.hLPF.YTick = [];
            app.resLPF = uiaxes(app.LowPassFilterTab);
            app.resLPF.XTick = [];
            app.resLPF.YTick = [];
            app.ExampleimageDropDownLabel = uilabel(app.LowPassFilterTab);
            app.ExampleimageDropDownLabel.Text = 'Example image';
            app.ExampleimageLPF = uidropdown(app.LowPassFilterTab);
            app.ExampleimageLPF.Items = {'Gedung Sate', 'Kupu-Kupu', 'Kapal', 'HerryPoter', 'Saturnus', 'Wajah', 'Polisi'};
            app.ExampleimageLPF.ValueChangedFcn = createCallbackFcn(app, @ExampleimageLPFValueChanged, true);
            app.ExampleimageLPF.Value = 'Gedung Sate';
            app.LoadimageButtonLPF = uibutton(app.LowPassFilterTab, 'push');
            app.LoadimageButtonLPF.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonLPFPushed, true);
            app.LoadimageButtonLPF.Text = 'Load image';
            app.ImageInputLabel = uilabel(app.LowPassFilterTab);
            app.ImageInputLabel.Text = 'Image Input';
            app.FourierSpectrumLabel = uilabel(app.LowPassFilterTab);
            app.FourierSpectrumLabel.Text = 'Fourier Spectrum';
            app.FourierFilterLabel = uilabel(app.LowPassFilterTab);
            app.FourierFilterLabel.Text = 'Fourier Filter';
            app.ImageResultLabel = uilabel(app.LowPassFilterTab);
            app.ImageResultLabel.Text = 'Image Result';
            app.LowPassFilterDropDownLabel = uilabel(app.LowPassFilterTab);
            app.LowPassFilterDropDownLabel.Text = 'Low Pass Filter';
            app.LowPassFilterDropDown = uidropdown(app.LowPassFilterTab);
            app.LowPassFilterDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.LowPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassFilterDropDownValueChanged, true);
            app.LowPassFilterDropDown.Value = 'Ideal';
            app.HighPassFilterTab = uitab(app.TabGroup);
            app.HighPassFilterTab.Title = 'High Pass Filter';
            app.InputHPF = uiaxes(app.HighPassFilterTab);
            app.InputHPF.XTick = [];
            app.InputHPF.YTick = [];
            app.fsHPF = uiaxes(app.HighPassFilterTab);
            app.fsHPF.XTick = [];
            app.fsHPF.YTick = [];
            app.hHPF = uiaxes(app.HighPassFilterTab);
            app.hHPF.XTick = [];
            app.hHPF.YTick = [];
            app.resHPF = uiaxes(app.HighPassFilterTab);
            app.resHPF.XTick = [];
            app.resHPF.YTick = [];
            app.LoadimageButtonHPF = uibutton(app.HighPassFilterTab, 'push');
            app.LoadimageButtonHPF.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonHPFPushed, true);
            app.LoadimageButtonHPF.Text = 'Load image';
            app.ImageInputLabel_2 = uilabel(app.HighPassFilterTab);
            app.ImageInputLabel_2.Text = 'Image Input';
            app.FourierSpectrumLabel_2 = uilabel(app.HighPassFilterTab);
            app.FourierSpectrumLabel_2.Text = 'Fourier Spectrum';
            app.FourierFilterLabel_2 = uilabel(app.HighPassFilterTab);
            app.FourierFilterLabel_2.Text = 'Fourier Filter';
            app.ImageResultLabel_2 = uilabel(app.HighPassFilterTab);
            app.ImageResultLabel_2.Text = 'Image Result';
            app.ExampleimageDropDownLabel_2 = uilabel(app.HighPassFilterTab);
            app.ExampleimageDropDownLabel_2.Text = 'Example image';
            app.ExampleimageHPF = uidropdown(app.HighPassFilterTab);
            app.ExampleimageHPF.Items = {'Rumah', 'Kucing', 'Bakar', 'Gajah Duduk'};
            app.ExampleimageHPF.ValueChangedFcn = createCallbackFcn(app, @ExampleimageHPFValueChanged, true);
            app.ExampleimageHPF.Value = 'Rumah';
            app.LowPassFilterDropDown_2Label = uilabel(app.HighPassFilterTab);
            app.LowPassFilterDropDown_2Label.Text = 'Low Pass Filter';
            app.HighPassFilterDropDown = uidropdown(app.HighPassFilterTab);
            app.HighPassFilterDropDown.Items = {'Ideal', 'Gaussian', 'Butterworth'};
            app.HighPassFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassFilterDropDownValueChanged, true);
            app.HighPassFilterDropDown.Value = 'Ideal';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.ImageInput = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput.XLim = [0 1];
            app.ImageInput.XTick = [];
            app.ImageInput.YTick = [];
            app.GreenAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput, 'Green')
            xlabel(app.GreenAxesInput, 'Intensity')
            ylabel(app.GreenAxesInput, 'Pixels')
            app.GreenAxesInput.XLim = [0 255];
            app.GreenAxesInput.XTick = [0 128 255];
            app.RedAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput, 'Red')
            xlabel(app.RedAxesInput, 'Intensity')
            ylabel(app.RedAxesInput, 'Pixels')
            app.RedAxesInput.XLim = [0 255];
            app.RedAxesInput.XTick = [0 128 255];
            app.BlueAxesInput = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput, 'Blue')
            xlabel(app.BlueAxesInput, 'Intensity')
            ylabel(app.BlueAxesInput, 'Pixels')
            app.BlueAxesInput.XLim = [0 255];
            app.BlueAxesInput.XTick = [0 128 255];
            app.ImageInput_2 = uiaxes(app.HistogramEqualizationTab);
            app.ImageInput_2.XLim = [0 1];
            app.ImageInput_2.XTick = [];
            app.ImageInput_2.YTick = [];
            app.GreenAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.GreenAxesInput_2, 'Green')
            xlabel(app.GreenAxesInput_2, 'Intensity')
            ylabel(app.GreenAxesInput_2, 'Pixels')
            app.GreenAxesInput_2.XLim = [0 255];
            app.GreenAxesInput_2.XTick = [0 128 255];
            app.RedAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.RedAxesInput_2, 'Red')
            xlabel(app.RedAxesInput_2, 'Intensity')
            ylabel(app.RedAxesInput_2, 'Pixels')
            app.RedAxesInput_2.XLim = [0 255];
            app.RedAxesInput_2.XTick = [0 128 255];
            app.BlueAxesInput_2 = uiaxes(app.HistogramEqualizationTab);
            title(app.BlueAxesInput_2, 'Blue')
            xlabel(app.BlueAxesInput_2, 'Intensity')
            ylabel(app.BlueAxesInput_2, 'Pixels')
            app.BlueAxesInput_2.XLim = [0 255];
            app.BlueAxesInput_2.XTick = [0 128 255];
            app.ResultLabel = uilabel(app.HistogramEqualizationTab);
            app.ResultLabel.Text = 'Result';
            app.InputLabel = uilabel(app.HistogramEqualizationTab);
            app.InputLabel.Text = 'Input';
            app.ExampleImageDropDownLabel = uilabel(app.HistogramEqualizationTab);
            app.ExampleImageDropDownLabel.Text = 'Example Image';
            app.ExampleImageHisteq = uidropdown(app.HistogramEqualizationTab);
            app.ExampleImageHisteq.Items = {'Kacang', 'Kota', 'Jembatan', 'Ruangan', 'Pacar Rahadian', 'Kucing'};
            app.ExampleImageHisteq.Value = 'Kacang';
            app.LoadImageButton = uibutton(app.HistogramEqualizationTab, 'push');
            app.LoadImageButton.Text = 'Load Image';
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.ImageInputHisspec = uiaxes(app.HistogramSpecificationTab);
            app.ImageInputHisspec.XLim = [0 1];
            app.ImageInputHisspec.XTick = [];
            app.ImageInputHisspec.YTick = [];
            app.GreenAxesInputHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.GreenAxesInputHisspec, 'Green')
            xlabel(app.GreenAxesInputHisspec, 'Intensity')
            ylabel(app.GreenAxesInputHisspec, 'Pixels')
            app.GreenAxesInputHisspec.XLim = [0 255];
            app.GreenAxesInputHisspec.XTick = [0 128 255];
            app.RedAxesInputHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.RedAxesInputHisspec, 'Red')
            xlabel(app.RedAxesInputHisspec, 'Intensity')
            ylabel(app.RedAxesInputHisspec, 'Pixels')
            app.RedAxesInputHisspec.XLim = [0 255];
            app.RedAxesInputHisspec.XTick = [0 128 255];
            app.BlueAxesInputHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.BlueAxesInputHisspec, 'Blue')
            xlabel(app.BlueAxesInputHisspec, 'Intensity')
            ylabel(app.BlueAxesInputHisspec, 'Pixels')
            app.BlueAxesInputHisspec.XLim = [0 255];
            app.BlueAxesInputHisspec.XTick = [0 128 255];
            app.ImageResultHisspec = uiaxes(app.HistogramSpecificationTab);
            app.ImageResultHisspec.XLim = [0 1];
            app.ImageResultHisspec.XTick = [];
            app.ImageResultHisspec.YTick = [];
            app.GreenAxesResultHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.GreenAxesResultHisspec, 'Green')
            xlabel(app.GreenAxesResultHisspec, 'Intensity')
            ylabel(app.GreenAxesResultHisspec, 'Pixels')
            app.GreenAxesResultHisspec.XLim = [0 255];
            app.GreenAxesResultHisspec.XTick = [0 128 255];
            app.RedAxesResultHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.RedAxesResultHisspec, 'Red')
            xlabel(app.RedAxesResultHisspec, 'Intensity')
            ylabel(app.RedAxesResultHisspec, 'Pixels')
            app.RedAxesResultHisspec.XLim = [0 255];
            app.RedAxesResultHisspec.XTick = [0 128 255];
            app.BlueAxesResultHisspec = uiaxes(app.HistogramSpecificationTab);
            title(app.BlueAxesResultHisspec, 'Blue')
            xlabel(app.BlueAxesResultHisspec, 'Intensity')
            ylabel(app.BlueAxesResultHisspec, 'Pixels')
            app.BlueAxesResultHisspec.XLim = [0 255];
            app.BlueAxesResultHisspec.XTick = [0 128 255];
            app.ImageRef = uiaxes(app.HistogramSpecificationTab);
            app.ImageRef.XLim = [0 1];
            app.ImageRef.XTick = [];
            app.ImageRef.YTick = [];
            app.GreenAxesRef = uiaxes(app.HistogramSpecificationTab);
            title(app.GreenAxesRef, 'Green')
            xlabel(app.GreenAxesRef, 'Intensity')
            ylabel(app.GreenAxesRef, 'Pixels')
            app.GreenAxesRef.XLim = [0 255];
            app.GreenAxesRef.XTick = [0 128 255];
            app.RedAxesRef = uiaxes(app.HistogramSpecificationTab);
            title(app.RedAxesRef, 'Red')
            xlabel(app.RedAxesRef, 'Intensity')
            ylabel(app.RedAxesRef, 'Pixels')
            app.RedAxesRef.XLim = [0 255];
            app.RedAxesRef.XTick = [0 128 255];
            app.BlueAxesRef = uiaxes(app.HistogramSpecificationTab);
            title(app.BlueAxesRef, 'Blue')
            xlabel(app.BlueAxesRef, 'Intensity')
            ylabel(app.BlueAxesRef, 'Pixels')
            app.BlueAxesRef.XLim = [0 255];
            app.BlueAxesRef.XTick = [0 128 255];
            app.ResultLabel_3 = uilabel(app.HistogramSpecificationTab);
            app.ResultLabel_3.Text = 'Result';
            app.InputLabel_3 = uilabel(app.HistogramSpecificationTab);
            app.InputLabel_3.Text = 'Input';
            app.LoadImageButtonHisspec = uibutton(app.HistogramSpecificationTab, 'push');
            app.LoadImageButtonHisspec.Text = 'Load Image';
            app.ExampleImageDropDownLabel_3 = uilabel(app.HistogramSpecificationTab);
            app.ExampleImageDropDownLabel_3.Text = 'Example Image';
            app.ExampleImageHisspec = uidropdown(app.HistogramSpecificationTab);
            app.ExampleImageHisspec.Items = {'Bridge', 'Girl', 'Pacar Rahadian'};
            app.ExampleImageHisspec.Value = 'Bridge';
            app.ReferenceLabel = uilabel(app.HistogramSpecificationTab);
            app.ReferenceLabel.Text = 'Reference';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end